classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        RGBCheckBox                   matlab.ui.control.CheckBox
        DotsperinchDPIEditFieldLabel  matlab.ui.control.Label
        DPIField                      matlab.ui.control.NumericEditField
        Colonyareaa_mina_maxEditFieldLabel  matlab.ui.control.Label
        ColonyAreaField               matlab.ui.control.NumericEditField
        GaussianfiltersizesxsyEditFieldLabel  matlab.ui.control.Label
        GaussFiltField                matlab.ui.control.NumericEditField
        TophatfilteringstructuringelementsizepxEditFieldLabel  matlab.ui.control.Label
        TopHatField                   matlab.ui.control.NumericEditField
        WatershedvectorhminstephmaxEditFieldLabel  matlab.ui.control.Label
        WatershedVecField             matlab.ui.control.NumericEditField
        OpeningClosingbyReconstructionelementsizepxEditFieldLabel  matlab.ui.control.Label
        ObrcbrField                   matlab.ui.control.NumericEditField
        ImagePropertiesLabel          matlab.ui.control.Label
        SegmentationPropertiesLabel   matlab.ui.control.Label
        ImageDirectoriesLabel         matlab.ui.control.Label
        StartSegmentationButton       matlab.ui.control.Button
        Intensitythreshold01Label     matlab.ui.control.Label
        IntensityThreshField          matlab.ui.control.NumericEditField
        WatershedVecField2            matlab.ui.control.NumericEditField
        WatershedVecField3            matlab.ui.control.NumericEditField
        GaussFiltField2               matlab.ui.control.NumericEditField
        ColonyAreaField2              matlab.ui.control.NumericEditField
        BrowseImageFolderButton       matlab.ui.control.Button
        ImageFolderField              matlab.ui.control.EditField
        BrowseTemplateButton          matlab.ui.control.Button
        ImageTemplateField            matlab.ui.control.EditField
        BrowseDestButton              matlab.ui.control.Button
        ImageDestField                matlab.ui.control.EditField
        ImageFolderLabel              matlab.ui.control.Label
        ImageTemplateLabel            matlab.ui.control.Label
        ImageDestLabel                matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        ImagePanelLabel               matlab.ui.control.Label
        FilenameEditFieldLabel        matlab.ui.control.Label
        FilenameField                 matlab.ui.control.EditField
        Image                         matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseImageFolderButton
        function BrowseImageFolderButtonPushed(app, event)
            sourcepath_img_colonies = uigetdir('C:\', 'Choose image folder');
            app.ImageFolderField.Value = sourcepath_img_colonies;
        end

        % Button pushed function: BrowseTemplateButton
        function BrowseTemplateButtonPushed(app, event)
            [file, path, ~] = uigetfile({'*.tif'; '*.jpg'; '*.jpeg,'; '*.png'; '*.bmp'}, ...
                'C:\', 'Choose template (cell container) image',  ...
                'MultiSelect', 'off');
            sourcepath_img_template = fullfile(path, file);
            app.ImageTemplateField.Value = sourcepath_img_template;
        end

        % Button pushed function: BrowseDestButton
        function BrowseDestButtonPushed(app, event)
            destpath_img_colonies = uigetdir('C:\', 'Choose destination folder');
            app.ImageDestField.Value = destpath_img_colonies;
        end

        % Button pushed function: StartSegmentationButton
        function StartSegmentationButtonPushed(app, event)
            
            sourcepath_img_colonies{1} = app.ImageFolderField.Value;
            sourcepath_img_template = app.ImageTemplateField.Value;
            destpath_img_colonies = app.ImageDestField.Value;
            
            % Binary flag for color image
            rgb_flag = app.RGBCheckBox.Value;
            
            % Pixel size in mm/pixel (inch in mm per dpi)
            param.px_size = 25.4/app.DPIField.Value;
            
            % Define a search interval for colony area
            area = [app.ColonyAreaField.Value ...
                app.ColonyAreaField2.Value];
            param.area = [area(1)/2 area(1) area(2) area(2)*2];
            
            % Gaussian filter size
            param.gaussfilt_size = [app.GaussFiltField.Value ...
                app.GaussFiltField2.Value];
            
            % Opening-Closing-by-Reconstruction structuring element size
            param.obrcbr_size = app.ObrcbrField.Value;
            
            % Top-hat filtering size
            param.tophat_size = app.TopHatField.Value;
            
            % Watershed segmentation vector
            param.ws_vec = app.WatershedVecField.Value: ...
                app.WatershedVecField2.Value: ...
                app.WatershedVecField3.Value;
            
            % Intensity threshold
            param.intensity_thresh = app.IntensityThreshField.Value;
            
            % Loop through source directories
            
            if rgb_flag == 1
                
                for i = 1:length(sourcepath_img_colonies)
                    
                    folderList = getAllFolders(sourcepath_img_colonies{i});
                    
                    for j = 1:length(folderList)
                        [path1, foldername1, ~] = fileparts(folderList{j});
                        [path2, foldername2, ~] = fileparts(path1);
                        destpath = fullfile(destpath_img_colonies, foldername2);
                        if ~exist(destpath, 'dir')
                            mkdir(destpath)
                        end
                        destpath = fullfile(destpath, foldername1);
                        if ~exist(destpath, 'dir')
                            mkdir(destpath)
                        end
                        
                        fileList = getAllFiles(folderList{j});
                        
                        filename_vec = {};
                        confluency_vec = [];
                        n_colony = [];
                        mean_area   = []; SD_area   = [];
                        mean_red    = []; SD_red    = [];
                        mean_green  = []; SD_green  = [];
                        mean_blue   = []; SD_blue   = [];
                        mean_gray   = []; SD_gray   = [];
                        mean_pca1   = []; SD_pca1   = [];
                        mean_pca2   = []; SD_pca2   = [];
                        
                        for k = 1:length(fileList)
                            
                            [~, filename, ext] = fileparts(fileList{k});
                            
                            t = tic;
                            [confluency, areastats, redstats, greenstats, bluestats, ...
                                graystats, pca1stats, pca2stats] = ...
                                CellSegmentation(fileList{k}, ...
                                fileList{k}, destpath, param, ...
                                app.UIFigure); % sourcepath_img_template
                            runtime = toc(t);
                            
                            app.FilenameField.Value = [filename ext];                        
                            app.Image.ImageSource = ...
                                fullfile(destpath, sprintf('%s-Seg.png', filename));
                            
                            if ~isempty(areastats)
                                
                                colony_xyCentroids = vertcat(areastats.Centroid);
                                colony_xCentroids = colony_xyCentroids(:,1);
                                colony_yCentroids = colony_xyCentroids(:,2);
                                colony_areas = [areastats.Area]' .* param.px_size^2;
                                confluency_vec(k) = confluency * 100;
                                
                            else
                                
                                colony_xCentroids = 0;
                                colony_yCentroids = 0;
                                colony_areas = 0;
                                confluency_vec(k) = 0;
                                
                            end
                            
                            colony_MedianRed    = [redstats.MedianIntensity]';
                            colony_MedianGreen  = [greenstats.MedianIntensity]';
                            colony_MedianBlue   = [bluestats.MedianIntensity]';
                            colony_MedianGray   = [graystats.MedianIntensity]';
                            colony_MedianPCA1   = [pca1stats.MedianIntensity]';
                            colony_MedianPCA2   = [pca2stats.MedianIntensity]';
                            colony_SDRed        = [redstats.SD]';
                            colony_SDGreen      = [greenstats.SD]';
                            colony_SDBlue       = [bluestats.SD]';
                            colony_SDGray       = [graystats.SD]';
                            colony_SDPCA1       = [pca1stats.SD]';
                            colony_SDPCA2       = [pca2stats.SD]';
                            
                            filename_vec{k} = filename;
                            n_colony(k)     = numel(areastats);
                            mean_area(k)    = mean(colony_areas);
                            mean_red(k)     = mean(colony_MedianRed);
                            mean_green(k)   = mean(colony_MedianGreen);
                            mean_blue(k)    = mean(colony_MedianBlue);
                            mean_gray(k)    = mean(colony_MedianGray);
                            mean_pca1(k)    = mean(colony_MedianPCA1);
                            mean_pca2(k)    = mean(colony_MedianPCA2);
                            SD_area(k)      = mean(colony_areas);
                            SD_red(k)       = mean(colony_MedianRed);
                            SD_green(k)     = mean(colony_MedianGreen);
                            SD_blue(k)      = mean(colony_MedianBlue);
                            SD_gray(k)      = mean(colony_MedianGray);
                            SD_pca1(k)      = mean(colony_MedianPCA1);
                            SD_pca2(k)      = mean(colony_MedianPCA2);
                            
                            % pixelindexlist{k} = cat(1,areastats_colony.PixelIdxList);
                            
                            % Write results to .csv file
                            varNames = {'Colony Area (mm2)',    ...
                                'Centroid x-Coordinate (px)',   ...
                                'Centroid y-Coordinate (px)',   ...
                                'Median Colony Red (a.u)',      ...
                                'Median Colony Green (a.u)',    ...
                                'Median Colony Blue (a.u)',     ...
                                'Median Colony Gray (a.u)',     ...
                                'Median Colony PCA1 (a.u)',     ...
                                'Median Colony PCA2 (a.u)',     ...
                                'SD Colony Red (a.u)',          ...
                                'SD Colony Green (a.u)',        ...
                                'SD Colony Blue (a.u)',         ...
                                'SD Colony Gray (a.u)',         ...
                                'SD Colony PCA1 (a.u)',         ...
                                'SD Colony PCA2 (a.u)'};
                            T = table(colony_areas,     ...
                                colony_xCentroids,      ...
                                colony_yCentroids,      ...
                                colony_MedianRed,       ...
                                colony_MedianGreen,     ...
                                colony_MedianBlue,      ...
                                colony_MedianGray,      ...
                                colony_MedianPCA1,      ...
                                colony_MedianPCA2,      ...
                                colony_SDRed,           ...
                                colony_SDGreen,         ...
                                colony_SDBlue,          ...
                                colony_SDGray,          ...
                                colony_SDPCA1,          ...
                                colony_SDPCA2,          ...
                                'VariableNames', varNames);
                            writetable(T, fullfile(destpath, ...
                                sprintf('%s-ColonyData.csv', filename)),  ...
                                'Delimiter', ',', 'QuoteStrings', true)
                            
                        end
                        
                        varNames  = {'Filename',    ...
                            'Colony count',         ...
                            'Mean size (mm2)',      ...
                            'SD size (mm2)',        ...
                            'Confluency (%)',       ...
                            'Mean Red (a.u)',       ...
                            'SD Red (a.u)',         ...
                            'Mean Green (a.u)',     ...
                            'SD Green (a.u)',       ...
                            'Mean Blue (a.u)',      ...
                            'SD Blue (a.u)',        ...
                            'Mean Gray (a.u)',      ...
                            'SD Gray (a.u)',        ...
                            'Mean PCA1 (a.u)',      ...
                            'SD PCA1 (a.u)',        ...
                            'Mean PCA2 (a.u)',      ...
                            'SD PCA2 (a.u)'};
                        T = table(filename_vec',     ...
                            n_colony',              ...
                            mean_area',             ...
                            SD_area',               ...
                            confluency_vec',        ...
                            mean_red',              ...
                            SD_red',                ...
                            mean_green',            ...
                            SD_green',              ...
                            mean_blue',             ...
                            SD_blue',               ...
                            mean_gray',             ...
                            SD_gray',               ...
                            mean_pca1',             ...
                            SD_pca1',               ...
                            mean_pca2',             ...
                            SD_pca2',               ...
                            'VariableNames', varNames);
                        writetable(T, fullfile(destpath, [foldername2 '.csv']),  ...
                            'Delimiter', ',', 'QuoteStrings', true)
                        
                    end
                    
                end
                
            else
                
                for i = 1:length(sourcepath_img_colonies)
                    
                    folderList = getAllFolders(sourcepath_img_colonies{i});
                    
                    for j = 1:length(folderList)
                        [path1, foldername1, ~] = fileparts(folderList{j});
                        [path2, foldername2, ~] = fileparts(path1);
                        destpath = fullfile(destpath_img_colonies, foldername2);
                        if ~exist(destpath, 'dir')
                            mkdir(destpath)
                        end
                        destpath = fullfile(destpath, foldername1);
                        if ~exist(destpath, 'dir')
                            mkdir(destpath)
                        end
                        
                        fileList = getAllFiles(folderList{j});
                        
                        filename_vec = {};
                        confluency_vec = [];
                        n_colony = [];
                        mean_area       = []; SD_area       = [];
                        mean_intensity  = []; SD_intensity  = [];
                        mean_pca1       = []; SD_pca1       = [];
                        
                        for k = 1:length(fileList)
                            
                            [~, filename, ext] = fileparts(fileList{k});
                            
                            t = tic;
                            [confluency, areastats, imgstats, pca1stats] = ...
                                CellSegmentation(fileList{k}, ...
                                fileList{k}, destpath, param, ...
                                app.UIFigure); % sourcepath_img_template
                            runtime = toc(t);
                            
                            app.FilenameField.Value = [filename ext];
                            app.Image.ImageSource = ...
                                fullfile(destpath, sprintf('%s-Seg.png', filename));
                            
                            if ~isempty(areastats)
                                
                                colony_xyCentroids = vertcat(areastats.Centroid);
                                colony_xCentroids = colony_xyCentroids(:,1);
                                colony_yCentroids = colony_xyCentroids(:,2);
                                colony_areas = [areastats.Area]' .* param.px_size^2;
                                confluency_vec(k) = confluency * 100;
                                
                            else
                                
                                colony_xCentroids = 0;
                                colony_yCentroids = 0;
                                colony_areas = 0;
                                confluency_vec(k) = 0;
                                
                            end
                            
                            colony_MedianIntensity  = [imgstats.MedianIntensity]';
                            colony_MedianPCA1       = [pca1stats.MedianIntensity]';
                            colony_SDIntensity      = [imgstats.SD]';
                            colony_SDPCA1           = [pca1stats.SD]';
                            
                            filename_vec{k}     = filename;
                            n_colony(k)         = numel(areastats);
                            mean_area(k)        = mean(colony_areas);
                            mean_intensity(k)   = mean(colony_MedianIntensity);
                            mean_pca1(k)        = mean(colony_MedianPCA1);
                            SD_area(k)          = mean(colony_areas);
                            SD_intensity(k)     = mean(colony_SDIntensity);
                            SD_pca1(k)          = mean(colony_SDPCA1);
                            
                            % pixelindexlist{k} = cat(1,areastats_colony.PixelIdxList);
                            
                            % Write results to .csv file
                            varNames = {'Colony Area (mm2)',        ...
                                'Centroid x-Coordinate (px)',       ...
                                'Centroid y-Coordinate (px)',       ...
                                'Median Colony Intensity (a.u)',    ...
                                'Median Colony PCA1 (a.u)',         ...
                                'SD Colony Intensity (a.u)',        ...
                                'SD Colony PCA1 (a.u)'};
                            T = table(colony_areas, ...
                                colony_xCentroids,  ...
                                colony_yCentroids,  ...
                                colony_MedianIntensity, ...
                                colony_MedianPCA1,  ...
                                colony_SDIntensity,     ...
                                colony_SDPCA1,      ...
                                'VariableNames', varNames);
                            writetable(T, fullfile(destpath, ...
                                sprintf('%s-ColonyData.csv', filename)),  ...
                                'Delimiter', ',', 'QuoteStrings', true)
                            
                        end
                        
                        varNames  = {'Filename',    ...
                            'Colony count',         ...
                            'Mean size (mm2)',      ...
                            'SD size (mm2)',        ...
                            'Confluency (%)',       ...
                            'Mean Intensity (a.u)', ...
                            'SD Intensity (a.u)',   ...
                            'Mean PCA1 (a.u)',      ...
                            'SD PCA1 (a.u)'};
                        T = table(filename_vec',    ...
                            n_colony',              ...
                            mean_area',             ...
                            SD_area',               ...
                            confluency_vec',        ...
                            mean_intensity',        ...
                            SD_image',              ...
                            mean_pca1',             ...
                            SD_pca1',               ...
                            'VariableNames', varNames);
                        writetable(T, fullfile(destpath, [foldername2 '.csv']),  ...
                            'Delimiter', ',', 'QuoteStrings', true)
                        
                    end
                    
                end
                
            end
            
            delete(gcp)
            
            clc;
            clear all;
            close all;
            delete(findall(0, 'type', 'figure', 'tag', 'TMWWaitbar'));
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {586, 586};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {329, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RGBCheckBox = uicheckbox(app.LeftPanel);
            app.RGBCheckBox.Text = 'Color (RGB) image';
            app.RGBCheckBox.Value = true;
            app.DotsperinchDPIEditFieldLabel = uilabel(app.LeftPanel);
            app.DotsperinchDPIEditFieldLabel.Text = 'Dots per inch (DPI)';
            app.DPIField = uieditfield(app.LeftPanel, 'numeric');
            app.DPIField.Limits = [0 Inf];
            app.DPIField.Value = 1200;
            app.Colonyareaa_mina_maxEditFieldLabel = uilabel(app.LeftPanel);
            app.Colonyareaa_mina_maxEditFieldLabel.Text = 'Colony area ([a_min a_max])';
            app.ColonyAreaField = uieditfield(app.LeftPanel, 'numeric');
            app.ColonyAreaField.Limits = [0 Inf];
            app.ColonyAreaField.Value = 110;
            app.GaussianfiltersizesxsyEditFieldLabel = uilabel(app.LeftPanel);
            app.GaussianfiltersizesxsyEditFieldLabel.Text = 'Gaussian filter size ([sx sy])';
            app.GaussFiltField = uieditfield(app.LeftPanel, 'numeric');
            app.GaussFiltField.Limits = [0 Inf];
            app.GaussFiltField.RoundFractionalValues = 'on';
            app.GaussFiltField.Value = 3;
            app.TophatfilteringstructuringelementsizepxEditFieldLabel = uilabel(app.LeftPanel);
            app.TophatfilteringstructuringelementsizepxEditFieldLabel.Text = {'Top-hat filtering '; 'structuring element size (px)'};
            app.TopHatField = uieditfield(app.LeftPanel, 'numeric');
            app.TopHatField.Limits = [0 Inf];
            app.TopHatField.RoundFractionalValues = 'on';
            app.TopHatField.Value = 90;
            app.WatershedvectorhminstephmaxEditFieldLabel = uilabel(app.LeftPanel);
            app.WatershedvectorhminstephmaxEditFieldLabel.Text = 'Watershed vector ([hmin:step:hmax])';
            app.WatershedVecField = uieditfield(app.LeftPanel, 'numeric');
            app.WatershedVecField.Limits = [0 1];
            app.WatershedVecField.Value = 0.15;
            app.OpeningClosingbyReconstructionelementsizepxEditFieldLabel = uilabel(app.LeftPanel);
            app.OpeningClosingbyReconstructionelementsizepxEditFieldLabel.Text = {'Opening-Closing-by-Reconstruction '; 'structuring element size (px)'};
            app.ObrcbrField = uieditfield(app.LeftPanel, 'numeric');
            app.ObrcbrField.Limits = [0 Inf];
            app.ObrcbrField.RoundFractionalValues = 'on';
            app.ObrcbrField.Value = 6;
            app.ImagePropertiesLabel = uilabel(app.LeftPanel);
            app.ImagePropertiesLabel.Text = 'Image properties';
            app.SegmentationPropertiesLabel = uilabel(app.LeftPanel);
            app.SegmentationPropertiesLabel.Text = 'Segmentation parameters';
            app.ImageDirectoriesLabel = uilabel(app.LeftPanel);
            app.ImageDirectoriesLabel.Text = 'Directory selection';
            app.StartSegmentationButton = uibutton(app.LeftPanel, 'push');
            app.StartSegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @StartSegmentationButtonPushed, true);
            app.StartSegmentationButton.Text = 'Start segmentation';
            app.Intensitythreshold01Label = uilabel(app.LeftPanel);
            app.Intensitythreshold01Label.Text = 'Intensity threshold ([0,1])';
            app.IntensityThreshField = uieditfield(app.LeftPanel, 'numeric');
            app.IntensityThreshField.Limits = [0 1];
            app.IntensityThreshField.Value = 0.15;
            app.WatershedVecField2 = uieditfield(app.LeftPanel, 'numeric');
            app.WatershedVecField2.Limits = [0 1];
            app.WatershedVecField2.Value = 0.01;
            app.WatershedVecField3 = uieditfield(app.LeftPanel, 'numeric');
            app.WatershedVecField3.Limits = [0 1];
            app.WatershedVecField3.Value = 0.35;
            app.GaussFiltField2 = uieditfield(app.LeftPanel, 'numeric');
            app.GaussFiltField2.Limits = [0 Inf];
            app.GaussFiltField2.RoundFractionalValues = 'on';
            app.GaussFiltField2.Value = 3;
            app.ColonyAreaField2 = uieditfield(app.LeftPanel, 'numeric');
            app.ColonyAreaField2.Limits = [0 Inf];
            app.ColonyAreaField2.RoundFractionalValues = 'on';
            app.ColonyAreaField2.Value = 8000;
            app.BrowseImageFolderButton = uibutton(app.LeftPanel, 'push');
            app.BrowseImageFolderButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseImageFolderButtonPushed, true);
            app.BrowseImageFolderButton.Text = 'Browse';
            app.ImageFolderField = uieditfield(app.LeftPanel, 'text');
            app.BrowseTemplateButton = uibutton(app.LeftPanel, 'push');
            app.BrowseTemplateButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseTemplateButtonPushed, true);
            app.BrowseTemplateButton.Text = 'Browse';
            app.ImageTemplateField = uieditfield(app.LeftPanel, 'text');
            app.BrowseDestButton = uibutton(app.LeftPanel, 'push');
            app.BrowseDestButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseDestButtonPushed, true);
            app.BrowseDestButton.Text = 'Browse';
            app.ImageDestField = uieditfield(app.LeftPanel, 'text');
            app.ImageFolderLabel = uilabel(app.LeftPanel);
            app.ImageFolderLabel.Text = 'Choose image folder';
            app.ImageTemplateLabel = uilabel(app.LeftPanel);
            app.ImageTemplateLabel.Text = 'Choose image template file';
            app.ImageDestLabel = uilabel(app.LeftPanel);
            app.ImageDestLabel.Text = 'Choose destination folder';
            app.RightPanel = uipanel(app.GridLayout);
            app.ImagePanelLabel = uilabel(app.RightPanel);
            app.ImagePanelLabel.Text = 'Image panel';
            app.FilenameEditFieldLabel = uilabel(app.RightPanel);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameField = uieditfield(app.RightPanel, 'text');
            app.FilenameField.Editable = 'off';
            app.Image = uiimage(app.RightPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end